classdef TDTAnalysisGUIv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        SetPathWhereFoldersOpenButton   matlab.ui.control.Button
        WorkonPreviouslyImportedDataLabel  matlab.ui.control.Label
        ImportNewDataLabel              matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        SpecifySaveLocationButton       matlab.ui.control.Button
        RecordingParametersPanel        matlab.ui.container.Panel
        ImportParametersLabel           matlab.ui.control.Label
        OptionalExactRecordingIdentifiersCommaSeparatedListEditField  matlab.ui.control.EditField
        OptionalExactRecordingIdentifiersLabel  matlab.ui.control.Label
        BaseNamingSchemeEditField       matlab.ui.control.EditField
        BaseNamingSchemeLabel           matlab.ui.control.Label
        FilenamingSchemeLabel           matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        BadChannelsCommaSeparatedEditField  matlab.ui.control.EditField
        BadChannelsCommaSeparatedEditFieldLabel  matlab.ui.control.Label
        KnownBadChannelsCheckBox        matlab.ui.control.CheckBox
        SelectExperimentToImportButton  matlab.ui.control.Button
        ChooseImportableDataStreamsLabel  matlab.ui.control.Label
        LPText                          matlab.ui.control.NumericEditField
        LowPassCutoffHzEditFieldLabel   matlab.ui.control.Label
        HPText                          matlab.ui.control.NumericEditField
        HighPassCutoffHzEditFieldLabel  matlab.ui.control.Label
        FilterCheckBox                  matlab.ui.control.CheckBox
        OutlierDetectBox                matlab.ui.control.CheckBox
        ElectrodeSpacingEditField       matlab.ui.control.NumericEditField
        ElectrodeSpacingmmEditFieldLabel  matlab.ui.control.Label
        StimulationIntensitiesCommaSeparatedEditField  matlab.ui.control.EditField
        StimulationIntensitiesCommaSeparatedEditFieldLabel  matlab.ui.control.Label
        SweepChopDuration               matlab.ui.control.NumericEditField
        ChoppedWindowDurationsEditFieldLabel  matlab.ui.control.Label
        NumberofSweepsEditField         matlab.ui.control.NumericEditField
        NumberofSweepsEditFieldLabel    matlab.ui.control.Label
        NumberofRecordingsEditField     matlab.ui.control.NumericEditField
        NumberofRecordingsEditFieldLabel  matlab.ui.control.Label
        NumberofChannelsEditField       matlab.ui.control.NumericEditField
        NumberofChannelsEditFieldLabel  matlab.ui.control.Label
        SelectWindowsTab                matlab.ui.container.Tab
        LoadWindowInformationButton     matlab.ui.control.Button
        SaveWindowInformationButton     matlab.ui.control.Button
        SliceInfoText                   matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        OverrideYAxisCheckBox           matlab.ui.control.CheckBox
        FilteredWindowsCheckBox         matlab.ui.control.CheckBox
        DefineWindowPanel               matlab.ui.container.Panel
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        DeleteSelectedWindowButton      matlab.ui.control.Button
        DefinedWindowsDropDown          matlab.ui.control.DropDown
        DefinedWindowsDropDownLabel     matlab.ui.control.Label
        AcceptWindowButton              matlab.ui.control.Button
        WindowEndmsEditField            matlab.ui.control.NumericEditField
        WindowEndmsEditFieldLabel       matlab.ui.control.Label
        WindowStartmsEditField          matlab.ui.control.NumericEditField
        WindowStartmsEditFieldLabel     matlab.ui.control.Label
        AreaCalculationKnob             matlab.ui.control.DiscreteKnob
        AreaCalculationKnobLabel        matlab.ui.control.Label
        PeakValueSwitch                 matlab.ui.control.ToggleSwitch
        PeakValueSwitchLabel            matlab.ui.control.Label
        WindownameEditField             matlab.ui.control.EditField
        WindownameLabel                 matlab.ui.control.Label
        DataStreamButtonGroup           matlab.ui.container.ButtonGroup
        RawWindowButton                 matlab.ui.control.RadioButton
        VariableButtonGroup             matlab.ui.container.ButtonGroup
        CSDWindowButton                 matlab.ui.control.RadioButton
        LFPWindowButton                 matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        AnalyzeTab                      matlab.ui.container.Tab
        AnalyzeFilteredDataCheckBox     matlab.ui.control.CheckBox
        AnalyzeButton                   matlab.ui.control.Button
        AnalysesPanel                   matlab.ui.container.Panel
        MaximumSecondDerivativeCheckBox  matlab.ui.control.CheckBox
        MaximumFirstDerivativeCheckBox  matlab.ui.control.CheckBox
        AreaCheckBox                    matlab.ui.control.CheckBox
        PeakCheckBox                    matlab.ui.control.CheckBox
        VariablesPanel                  matlab.ui.container.Panel
        CSDsCheckBox                    matlab.ui.control.CheckBox
        LFPsCheckBox                    matlab.ui.control.CheckBox
        StreamstoAnalyzePanel           matlab.ui.container.Panel
        AnalyzeRawBox                   matlab.ui.control.CheckBox
    end


    properties (Access = public)
        Variables=struct(); % Description
        TreeChildren=struct(); % Description
    end

    methods (Access = private)

        function GatherVariablesForSelectWindowsTab(app)

            %             ImportBP60Flag=app.Variables.Flags.ImportBP60;
            %             ImportMultFlag=app.Variables.Flags.ImportMult;
            %             ImportFilterFlag=app.Variables.Flags.ImportFilterFlag;

            app.Variables.LFPs=struct();
            for ii=1:app.Variables.NumChannels
                TempLFPStruct=struct();
                TempLFPStruct.ChannelNumber=ii;
                TempLFPStruct.ChannelName=strcat('LFP ',num2str(ii));
                TempLFPStruct.Windows=struct();
                TempLFPStruct.AllWindowNames{1}='Select Window';
                app.Variables.LFPChannelNames{ii}=strcat('LFP ',num2str(ii));
                app.Variables.LFPs=setfield(app.Variables.LFPs,strcat('LFP_',num2str(ii)),TempLFPStruct);
            end

            app.Variables.CSDs=struct();
            for ii=1:app.Variables.NumChannels-2
                TempCSDStruct=struct();
                TempCSDStruct.ChannelNumber=ii;
                TempCSDStruct.ChannelName=strcat('CSD ',num2str(ii));
                TempCSDStruct.Windows=struct();
                TempCSDStruct.AllWindowNames{1}='Select Window';
                app.Variables.CSDChannelNames{ii}=strcat('CSD ',num2str(ii));
                app.Variables.CSDs=setfield(app.Variables.CSDs,strcat('CSD_',num2str(ii)),TempCSDStruct);
            end

            app.ChannelDropDown.Items=app.Variables.LFPChannelNames;
            app.ChannelDropDown.Value=app.Variables.LFPChannelNames{1};

            app.WindowStartmsEditField.Value=0;
            app.WindowEndmsEditField.Value=500;

            UpdateWindowPlot(app);
        end

        function [PlottedVariable,PlotStream,VariableName,PlottedChannel,timepts]= ChoosePlotVariable(app)

            PlotStream=app.DataStreamButtonGroup.SelectedObject.Text;
            Variablenums=[0 1];
            PlotVariable=Variablenums([app.LFPWindowButton.Value app.CSDWindowButton.Value]);
            if PlotVariable
                VariableName='CSD';
            else
                VariableName='LFP';
            end
            Filtered=app.FilteredWindowsCheckBox.Value;

            timepts=getfield(app.Variables,PlotStream,'timepts');
            if PlotVariable
                if Filtered
                    PlottedVariable=getfield(app.Variables,PlotStream,'FilteredCSDData');
                else
                    PlottedVariable=getfield(app.Variables,PlotStream,'CSDData');
                end
            else
                if Filtered
                    PlottedVariable=getfield(app.Variables,PlotStream,'AverageFilteredLFPData');
                else
                    PlottedVariable=getfield(app.Variables,PlotStream,'AverageLFPData');
                end
            end

            AllChannels=app.ChannelDropDown.Items;
            Channel=app.ChannelDropDown.Value;
            PlottedChannel=find(strcmp(AllChannels,Channel));
        end

        function [Stream,Variable,Channel,ChannelField] = CurrentSelections(app)
            Stream = app.DataStreamButtonGroup.SelectedObject.Text;
            Variable = app.VariableButtonGroup.SelectedObject.Text;

            AllChannels=app.ChannelDropDown.Items;
            Channel=app.ChannelDropDown.Value;
            CurrentChannel=find(strcmp(AllChannels,Channel));
            ChannelField=strcat(Variable(1:end-1),'_',num2str(CurrentChannel));
        end
    end

    methods (Access = public)

        function results = UpdateWindowPlot(app)
            %Set Parameters
            minX=app.WindowStartmsEditField.Value;
            maxX=app.WindowEndmsEditField.Value;

            [FullPlotVariable,StreamName,VariableName,PlottedChannel,timepts]=ChoosePlotVariable(app);
            PlottableVariable=squeeze(permute(FullPlotVariable(:,1,PlottedChannel,:),[1,4,2,3]));


            hold(app.UIAxes,'off')
            a=plot(timepts,PlottableVariable,'parent',app.UIAxes);
            hold(app.UIAxes,'on')

            if strcmp(VariableName,'LFP')
                app.UIAxes.YLabel.String= strcat('Volts');
            else
                app.UIAxes.YLabel.String= strcat('V/mm^2');
            end

            app.UIAxes.XLabel.String= 'Time (s)';
            if app.OverrideYAxisCheckBox.Value
                app.UIAxes.YAxis.Limits=[app.MinEditField.Value app.MaxEditField.Value];
            else
                app.UIAxes.YLimMode='auto';
            end
            app.UIAxes.XAxis.Limits=[minX/1000 maxX/1000];
            app.UIAxes.Title.String=strcat(app.ChannelDropDown.Value);

            OverlaySelectedWindow(app)
        end

        function results = OverlaySelectedWindow(app)

            [Stream,Variable,Channel,ChannelField] = CurrentSelections(app);
            ChannelWindows=getfield(app.Variables,Variable,ChannelField,'Windows');
            AllChannelWindowNames=getfield(app.Variables,Variable,ChannelField,'AllWindowNames');
            TotalWindows=length(AllChannelWindowNames)-2;

            if TotalWindows>0
                WindowName=app.DefinedWindowsDropDown.Value;
                WindowNumber=find(strcmp(AllChannelWindowNames,WindowName))-1;

                % if all windows
                if WindowNumber<1
                    %No Plotting

                elseif WindowNumber>TotalWindows
                    WindowColors=rand([TotalWindows,3]);
                    for iWindow=1:TotalWindows
                        hold(app.UIAxes,'on')
                        WindowExtents=ChannelWindows(iWindow).Extents;
                        x=[WindowExtents(1) WindowExtents(1) WindowExtents(2) WindowExtents(2)]./1000;
                        y=[app.UIAxes.YAxis.Limits fliplr(app.UIAxes.YAxis.Limits)];

                        fill(app.UIAxes,x,y,WindowColors(iWindow,:),'FaceAlpha',0.25)
                    end
                else
                    if TotalWindows==1
                        hold(app.UIAxes,'on')
                        WindowExtents=ChannelWindows.Extents;
                        x=[WindowExtents(1) WindowExtents(1) WindowExtents(2) WindowExtents(2)]./1000;
                        y=[app.UIAxes.YAxis.Limits fliplr(app.UIAxes.YAxis.Limits)];

                        fill(app.UIAxes,x,y,'r','FaceAlpha',0.25)
                    else
                        hold(app.UIAxes,'on')
                        WindowExtents=ChannelWindows(WindowNumber).Extents;
                        x=[WindowExtents(1) WindowExtents(1) WindowExtents(2) WindowExtents(2)]./1000;
                        y=[app.UIAxes.YAxis.Limits fliplr(app.UIAxes.YAxis.Limits)];

                        fill(app.UIAxes,x,y,'r','FaceAlpha',0.25)
                    end
                end
            end
        end
        
        function ChannelStruct = GetChannelWindowInformation(app)
            Stream = app.DataStreamButtonGroup.SelectedObject.Text;
            Variable = app.VariableButtonGroup.SelectedObject.Text;

            AllChannels=app.ChannelDropDown.Items;
            Channel=app.ChannelDropDown.Value;
            CurrentChannel=find(strcmp(AllChannels,Channel));
            ChannelField=strcat(Variable(1:end-1),'_',num2str(CurrentChannel));

            VariableStruct=getfield(app.Variables,Variable);
            ChannelStruct=getfield(VariableStruct,ChannelField);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set Tab Visibility
            app.Variables.Flags.SelectWindowsVisibility=0;
            app.Variables.Flags.AnalyzeVisibility=0;
            app.Variables.Flags.ExportPlotsVisibility=0;


            set(app.FilteredWindowsCheckBox,'Visible','off');
            set(app.BadChannelsCommaSeparatedEditField,'Visible','off')
            set(app.BadChannelsCommaSeparatedEditFieldLabel,'Visible','off')
            set(app.AnalyzeFilteredDataCheckBox,'Visible','off')

            addpath(genpath('Associated TDT Analysis Code Files'))

            app.Variables.MainFunctionFolder=pwd;
            if ismac
                app.Variables.Flags.OS='mac';
            elseif ispc
                app.Variables.Flags.OS='pc';
            end
            addpath(pwd);

        end

        % Button pushed function: SpecifySaveLocationButton
        function SpecifySaveLocationButtonPushed(app, event)
            if isfield(app.Variables,'OpenFrom')
                FolderPath=uigetdir(app.Variables.OpenFrom);
            else
            FolderPath=uigetdir;
            end
            if FolderPath~=0
                app.Variables.SaveFolder=FolderPath;
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            if isfield(app.Variables,'OpenFrom')
               [LoadFile,LoadPath]=uigetfile(app.Variables.OpenFrom);
            else
            [LoadFile,LoadPath]=uigetfile;
            end


            if LoadFile
                load(strcat(LoadPath,filesep,LoadFile))

                try
                    parentFolder=app.Variables.DataParentFolder;
                catch
                    SlashLocations=strfind(LoadPath,filesep);
                    parentFolder=LoadPath(1:SlashLocations(end));
                    app.Variables.DataParentFolder=parentFolder;
                end

                %Carry over defined opening location
                if isfield(app.Variables,'OpenFrom')
                OpenFrom = app.Variables.OpenFrom;
                app.Variables=SavedImportedData;
                app.Variables.OpenFrom=OpenFrom;
                else
                app.Variables=SavedImportedData;
                end

                %Identify if loaded data is from a previous analysis code version
                if isfield(app.Variables,'timepts')
                    app.Variables = ConvertV1toV3(app.Variables);
                end

                set(app.FilterCheckBox,'Value',app.Variables.Flags.ImportFilterFlag)
                if app.Variables.Flags.ImportFilterFlag
                    set(app.HPText,'Value',app.Variables.HighPassFilter)
                    set(app.LPText,'Value',app.Variables.LowPassFilter)
                    set(app.HPText,'Visible','on')
                    set(app.LPText,'Visible','on')
                end

                set(app.OutlierDetectBox,'Value',app.Variables.Flags.DetectOutliers);

                % Set Tab Visibility
                app.Variables.Flags.SelectWindowsVisibility=1;
                app.Variables.Flags.ExportPlotsVisibility=1;

                %Add Radiobuttons and check boxes based on imported streams
                NumSelectedStreams=length(app.Variables.DataStreamsImported);
                for iNode=1:NumSelectedStreams
                    CurrentStream=app.Variables.DataStreamsImported{iNode};
                    if iNode==1
                        app.DataStreamButtonGroup.Children.Text=CurrentStream;

                        app.AnalyzeTab.Children(5).Children.Text=CurrentStream;
                    else
                        rb=uiradiobutton(app.DataStreamButtonGroup,'Text',CurrentStream,'Position',app.DataStreamButtonGroup.Children(1).Position+[0 -20 0 0]);
                        cb=uicheckbox(app.AnalyzeTab.Children(5),'Text',CurrentStream,'Position',app.AnalyzeTab.Children(5).Children(1).Position+[0 -20 0 0]);
                    end
                end

                if ~isfield(app.Variables,'ImportFilterFlag')
                    app.Variables.ImportFilterFlag=0;
                end
                if app.Variables.Flags.ImportFilterFlag
                    set(app.FilteredWindowsCheckBox,'Visible','on');
                    set(app.AnalyzeFilteredDataCheckBox,'Visible','on');
                end

                app.SliceInfoText.Text=app.Variables.DataParentFolder;

                %Update Slice Info
                GatherVariablesForSelectWindowsTab(app);
                
                app.TabGroup.SelectedTab=app.SelectWindowsTab;

                %                 app.SelectWindowsTab.HandleVisibility = 'on';
            else
                warning('Folder Not Selected')
            end

        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)

            if size(app.Tree.CheckedNodes,1)<1
                warning('No Data Stream Selected')
                return
            end
            %Determine if Analysis Folder already exists, otherwise create one
            FolderContents=dir(app.Variables.SaveFolder);
            AnalysisFolderexists=0;
            for pp=1:length(FolderContents)
                if strcmp(FolderContents(pp).name,'AnalysisGUIResults')
                    AnalysisFolderexists=1;
                    break
                end
            end
            if ~strcmp(app.Variables.SaveFolder(end-17:end),'AnalysisGUIResults')
                if ~AnalysisFolderexists
                    mkdir(app.Variables.SaveFolder,'AnalysisGUIResults')
                end
                app.Variables.SaveFolder=strcat(app.Variables.SaveFolder,filesep,'AnalysisGUIResults');
            end

            %Overriding parallelization while still debugging
            SkipParallel=1;

            if ~SkipParallel
                %Determine if enough memory to attempt parallelization
                WindowLength=app.SweepChopDuration.Value; %sec
                PreArtifactWindow=WindowLength*0.005; %sec
                PostArtifactWindow=WindowLength-PreArtifactWindow;
                LoadName=strcat(BaseName,num2str(1));

                data=SEV2mat(LoadName,'EventName','Raws'); %Load Test Data
                Fs=data.Raws.fs;
                dt=1/Fs;
                WindowIndices=floor(WindowLength*Fs);
                PreWindowIndices=floor(PreArtifactWindow*Fs);
                PostWindowIndices=WindowIndices-PreWindowIndices;

                %Start Parallel Pools
                if FeasiblePools<NumCores && FeasiblePools>1
                    poolobj = gcp('nocreate');
                    delete(poolobj);
                    parpool(FeasiblePools);
                    app.Variables.Flags.Parallelize=1;
                elseif FeasiblePools<=1
                    app.Variables.Flags.Parallelize=0;
                else
                    poolobj = gcp('nocreate');
                    delete(poolobj);
                    parpool(app.Variables.NumCores);
                    app.Variables.Flags.Parallelize=1;
                end

                clear data
            else
                app.Variables.Flags.Parallelize=0;
            end

            %Set Import Parameters
            app.Variables.Flags.ImportVersion='v3.1';


            app.Variables.Flags.DetectOutliers=app.OutlierDetectBox.Value;
            app.Variables.Flags.ImportFilterFlag=app.FilterCheckBox.Value;
            if app.Variables.Flags.ImportFilterFlag
                app.Variables.HighPassFilter=app.HPText.Value;
                app.Variables.LowPassFilter=app.LPText.Value;
            end
            app.Variables.ElectrodeSpacing=app.ElectrodeSpacingEditField.Value;
            app.Variables.SweepChopDuration=app.SweepChopDuration.Value;
            app.Variables.NumChannels=app.NumberofChannelsEditField.Value;
            % app.Variables.NumStims=app.NumberofRecordingsEditField.Value;
            app.Variables.NumSweeps=app.NumberofSweepsEditField.Value;
            StimValueString=app.StimulationIntensitiesCommaSeparatedEditField.Value;
            CommaLocations=strfind(StimValueString,',');
            if isempty(CommaLocations)
                AllStims=str2double(StimValueString);
            else
                for iComma=1:length(CommaLocations)+1
                    if iComma==1
                        AllStims(iComma)=str2double(StimValueString(1:CommaLocations(iComma)-1));
                    elseif iComma==length(CommaLocations)+1
                        AllStims(iComma)=str2double(StimValueString(CommaLocations(iComma-1)+1:end));
                    else
                        AllStims(iComma)=str2double(StimValueString(CommaLocations(iComma-1)+1:CommaLocations(iComma)-1));
                    end
                end
            end
            app.Variables.AllStims=AllStims;


            %Call Import Function
            if app.Variables.Flags.Parallelize
                %                 try
                iapp=ImportTDTDatav5_Parallel(app,Fs,dt);
                if ~isstruct(iapp)
                    app=ImportTDTDatav5(app);
                else
                    app=iapp;
                    clear iapp
                end
            else
                app=ImportTDTDatav5(app);
            end


            % Set Tab Visibility
            app.Variables.Flags.SelectWindowsVisibility=1;
            app.Variables.Flags.ExportPlotsVisibility=1;

            if ~isfield(app.Variables,'ImportFilterFlag')
                app.Variables.Flags.ImportFilterFlag=0;
            end
            if app.Variables.Flags.ImportFilterFlag
                set(app.FilteredWindowsCheckBox,'Visible','on');
                set(app.AnalyzeFilteredDataCheckBox,'Visible','on');
            end

            %Make RadioButtons
            NumSelectedStreams=size(app.Tree.CheckedNodes,1);
            for iNode=1:NumSelectedStreams
                CurrentStream=app.Tree.CheckedNodes(iNode).Text;

                if iNode==1
                    app.DataStreamButtonGroup.Children.Text=CurrentStream;

                    app.AnalyzeTab.Children(5).Children.Text=CurrentStream;
                else
                    rb=uiradiobutton(app.DataStreamButtonGroup,'Text',CurrentStream,'Position',app.DataStreamButtonGroup.Children(1).Position+[0 -20 0 0]);
                    cb=uicheckbox(app.AnalyzeTab.Children(5),'Text',CurrentStream,'Position',app.AnalyzeTab.Children(5).Children(1).Position+[0 -20 0 0]);
                end
            end

            app.SliceInfoText.Text=app.Variables.DataParentFolder;
            GatherVariablesForSelectWindowsTab(app);
            app.TabGroup.SelectedTab=app.SelectWindowsTab;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            %Controls for Select Windows Tab
            if strcmp(selectedTab.Title,'Select Windows') & ~app.Variables.Flags.SelectWindowsVisibility
                app.TabGroup.SelectedTab=app.LoadDataTab;
            end

            %Controls for Analyze Tab
            if strcmp(selectedTab.Title,'Analyze') & ~app.Variables.Flags.AnalyzeVisibility & ~app.Variables.Flags.SelectWindowsVisibility
                app.TabGroup.SelectedTab=app.LoadDataTab;
            elseif strcmp(selectedTab.Title,'Analyze') & ~app.Variables.Flags.AnalyzeVisibility & app.Variables.Flags.SelectWindowsVisibility
                app.TabGroup.SelectedTab=app.SelectWindowsTab;
            end

            %Controls for Export Plots Tab
            if strcmp(selectedTab.Title,'Export Plots') & ~app.Variables.Flags.ExportPlotsVisibility
                app.TabGroup.SelectedTab=app.LoadDataTab;
            end


        end

        % Value changed function: FilterCheckBox
        function FilterCheckBoxValueChanged(app, event)
            value = app.FilterCheckBox.Value;

            if value
                set(app.LPText,'Visible','on')
                set(app.HPText,'Visible','on')
                set(app.LowPassCutoffHzEditFieldLabel,'Visible','on')
                set(app.HighPassCutoffHzEditFieldLabel,'Visible','on')
            else
                set(app.LPText,'Visible','off')
                set(app.HPText,'Visible','off')
                set(app.LowPassCutoffHzEditFieldLabel,'Visible','off')
                set(app.HighPassCutoffHzEditFieldLabel,'Visible','off')
            end
        end

        % Selection changed function: DataStreamButtonGroup
        function DataStreamButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataStreamButtonGroup.SelectedObject;
            UpdateWindowPlot(app);
        end

        % Selection changed function: VariableButtonGroup
        function VariableButtonGroupSelectionChanged(app, event)
            selectedButton = app.VariableButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text,'LFPs')
                app.ChannelDropDown.Items=app.Variables.LFPChannelNames;
                app.ChannelDropDown.Value=app.Variables.LFPChannelNames{1};
            elseif strcmp(selectedButton.Text,'CSDs')
                app.ChannelDropDown.Items=app.Variables.CSDChannelNames;
                app.ChannelDropDown.Value=app.Variables.CSDChannelNames{1};
            end
            Stream = app.DataStreamButtonGroup.SelectedObject.Text;
            Variable = app.VariableButtonGroup.SelectedObject.Text;

            AllChannels=app.ChannelDropDown.Items;
            Channel=app.ChannelDropDown.Value;
            CurrentChannel=find(strcmp(AllChannels,Channel));
            ChannelField=strcat(Variable(1:end-1),'_',num2str(CurrentChannel));

            VariableStruct=getfield(app.Variables,Variable);
            ChannelStruct=getfield(VariableStruct,ChannelField);

            app.DefinedWindowsDropDown.Items=ChannelStruct.AllWindowNames;
            app.DefinedWindowsDropDown.Value='Select Window';

            UpdateWindowPlot(app);
        end

        % Value changed function: FilteredWindowsCheckBox
        function FilteredWindowsCheckBoxValueChanged(app, event)
            value = app.FilteredWindowsCheckBox.Value;
            UpdateWindowPlot(app);
        end

        % Value changed function: OverrideYAxisCheckBox
        function OverrideYAxisCheckBoxValueChanged(app, event)
            value = app.OverrideYAxisCheckBox.Value;

            if value
                app.MinEditField.Visible='on';
                app.MaxEditField.Visible='on';
                Lims=app.UIAxes.YAxis.Limits;
                app.MinEditField.Value=Lims(1);
                app.MaxEditField.Value=Lims(2);
            else
                app.MinEditField.Visible='off';
                app.MaxEditField.Visible='off';
            end
            UpdateWindowPlot(app);
        end

        % Value changed function: WindowEndmsEditField
        function WindowEndmsEditFieldValueChanged(app, event)
            value = app.WindowEndmsEditField.Value;
            UpdateWindowPlot(app);
        end

        % Value changed function: WindowStartmsEditField
        function WindowStartmsEditFieldValueChanged(app, event)
            value = app.WindowStartmsEditField.Value;
            UpdateWindowPlot(app);
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            value = app.MinEditField.Value;
            UpdateWindowPlot(app);
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            UpdateWindowPlot(app);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;

            Stream = app.DataStreamButtonGroup.SelectedObject.Text;
            Variable = app.VariableButtonGroup.SelectedObject.Text;

            AllChannels=app.ChannelDropDown.Items;
            Channel=app.ChannelDropDown.Value;
            CurrentChannel=find(strcmp(AllChannels,Channel));
            ChannelField=strcat(Variable(1:end-1),'_',num2str(CurrentChannel));

            VariableStruct=getfield(app.Variables,Variable);
            ChannelStruct=getfield(VariableStruct,ChannelField);

            app.DefinedWindowsDropDown.Items=ChannelStruct.AllWindowNames;
            app.DefinedWindowsDropDown.Value='Select Window';
            UpdateWindowPlot(app);
        end

        % Button pushed function: AcceptWindowButton
        function AcceptWindowButtonPushed(app, event)
            Stream = app.DataStreamButtonGroup.SelectedObject.Text;
            Variable = app.VariableButtonGroup.SelectedObject.Text;

            AllChannels=app.ChannelDropDown.Items;
            Channel=app.ChannelDropDown.Value;
            CurrentChannel=find(strcmp(AllChannels,Channel));
            ChannelField=strcat(Variable(1:end-1),'_',num2str(CurrentChannel));

            VariableStruct=getfield(app.Variables,Variable);
            ChannelStruct=getfield(VariableStruct,ChannelField);

            if isfield(ChannelStruct.Windows,'PeakPolarity')
                CurrentWindows=length(ChannelStruct.AllWindowNames)-2;
            else
                CurrentWindows=0;
            end

            ChannelStruct.AllWindowNames{CurrentWindows+3}='All';
            ChannelStruct.AllWindowNames{CurrentWindows+2}=app.WindownameEditField.Value;
            ChannelStruct.Windows(CurrentWindows+1).Name=app.WindownameEditField.Value;
            ChannelStruct.Windows(CurrentWindows+1).Extents=[app.WindowStartmsEditField.Value app.WindowEndmsEditField.Value];
            ChannelStruct.Windows(CurrentWindows+1).PeakPolarity=app.PeakValueSwitch.Value;
            ChannelStruct.Windows(CurrentWindows+1).AreaPolarity=app.AreaCalculationKnob.Value;

            app.Variables=setfield(app.Variables,Variable,ChannelField,ChannelStruct);
            app.DefinedWindowsDropDown.Items=ChannelStruct.AllWindowNames;
            app.DefinedWindowsDropDown.Value=app.WindownameEditField.Value;

            app.WindownameEditField.Value='';
                                OverlaySelectedWindow(app)
        end

        % Button pushed function: SelectExperimentToImportButton
        function SelectExperimentToImportButtonPushed(app, event)


            % Get user to select folder
            if isfield(app.Variables,'OpenFrom')
                FolderPath=uigetdir(app.Variables.OpenFrom,'Select the folder containing the experiment');
            else
                FolderPath=uigetdir([],'Select the folder containing the experiment');
            end
            SlashLocations=strfind(FolderPath,filesep);
            parentFolder=FolderPath(1:SlashLocations(end));
            

            %%%%
            % Testing
            BaseNamingScheme = app.BaseNamingSchemeEditField.Value;
            BaseSchemeLength = length(BaseNamingScheme);
            
            FoldContents = dir(FolderPath);
            FoldLocs=[];
            MatchesBaseNamingScheme=zeros(1,length(FoldContents));
            Numbering = nan(1,length(FoldContents));
            for cont = 1:length(FoldContents)
                if FoldContents(cont).isdir
                    if strcmp(FoldContents(cont).name,'.') | strcmp(FoldContents(cont).name,'..')
                        continue
                    else
                        FoldLocs = [FoldLocs cont];
                        FoldNames{cont} = FoldContents(cont).name;
                        MatchesBaseNamingScheme(cont) = ~isempty(strfind(FoldNames{cont},BaseNamingScheme));
                        if MatchesBaseNamingScheme(cont)
                            RemainingName = FoldNames{cont}(strfind(FoldNames{cont},BaseNamingScheme)+BaseSchemeLength:end);
                            NumberingLocations{cont}=isstrprop(RemainingName,'digit');
                            StartOfNumbering = find(NumberingLocations{cont},1);
                            EndOfNumbering = find(~NumberingLocations{cont},1);
                            if isempty(EndOfNumbering)
                                Numbering(cont)= str2double( RemainingName(StartOfNumbering:end)  );
                            else
                                Numbering(cont)= str2double( RemainingName(StartOfNumbering:EndOfNumbering)  );
                            end
                        end
                    end
                end
            end
            UsableFolds = find(MatchesBaseNamingScheme);
            app.Variables.ImportInfo.ImportContents = FoldContents(UsableFolds);
            app.Variables.ImportInfo.ImportNames = FoldNames(UsableFolds);
            app.Variables.ImportInfo.ImportFileValues = Numbering(UsableFolds);
            
            [app.Variables.ImportInfo.ImportSequence,app.Variables.ImportInfo.ImportOrder] = sort(app.Variables.ImportInfo.ImportFileValues);
            app.Variables.NumStims =  length(app.Variables.ImportInfo.ImportFileValues);
            %%%Old Methodology
            % StimNumLocations=strfind(FolderPath,'-');
            % BaseName=FolderPath(1:StimNumLocations(end));
            % app.Variables.BaseName=BaseName;
            app.Variables.DataParentFolder=parentFolder;
            if ~isfield(app.Variables,'SaveFolder')
                % app.Variables.SaveFolder=strcat(parentFolder);
                app.Variables.SaveFolder=FolderPath;
            end
            cd(parentFolder)

            FirstFilePath = [app.Variables.ImportInfo.ImportContents(find(app.Variables.ImportInfo.ImportOrder==1)).folder filesep app.Variables.ImportInfo.ImportContents(find(app.Variables.ImportInfo.ImportOrder==1)).name];
            data=TDTbin2mat(FirstFilePath);
            DataStreamNames=fieldnames(data.streams);
            app.Variables.DataStreamNames=DataStreamNames;
            NumStreams=length(DataStreamNames);

            TreeChildren=struct();
            for iNode=1:NumStreams

                a=uitreenode(app.Tree,'Text',DataStreamNames{iNode});
                TreeChildren=setfield(TreeChildren,DataStreamNames{iNode},a);
                TreeChildren2=setfield(TreeChildren,DataStreamNames{iNode},a);
            end

            app.TreeChildren=TreeChildren;

            datainfo=whos('data');
            DataSize=datainfo.bytes;
            [user,syst]=memory;
            SystemMemory=syst.PhysicalMemory.Total;
            AvailableMemory=syst.SystemMemory.Available;

            DataStreamMultiplier=NumStreams;

            app.Variables.NumCores=feature('numcores');
            MemoryFudgeFactor=4; %Determined via trial and error on development PC
            FeasiblePools=floor(AvailableMemory/(MemoryFudgeFactor*DataSize*DataStreamMultiplier));

            NumCores=feature('numcores');
            app.Variables.NumCores=NumCores;

            if FeasiblePools<NumCores && FeasiblePools>1

                app.Variables.Flags.Parallelize=1;
            elseif FeasiblePools<=1
                app.Variables.Flags.Parallelize=0;
            else

                FeasiblePools=NumCores;
                app.Variables.Flags.Parallelize=1;
            end

            app.Variables.FeasiblePools=FeasiblePools;


        end

        % Value changed function: KnownBadChannelsCheckBox
        function KnownBadChannelsCheckBoxValueChanged(app, event)
            value = app.KnownBadChannelsCheckBox.Value;
            if value
                set(app.BadChannelsCommaSeparatedEditField,'Visible','on')
                set(app.BadChannelsCommaSeparatedEditFieldLabel,'Visible','on')
            else
                set(app.BadChannelsCommaSeparatedEditField,'Visible','off')
                set(app.BadChannelsCommaSeparatedEditFieldLabel,'Visible','off')
                app.BadChannelsCommaSeparatedEditField.Value=[];
            end
        end

        % Value changed function: DefinedWindowsDropDown
        function DefinedWindowsDropDownValueChanged(app, event)
            value = app.DefinedWindowsDropDown.Value;
            ChannelStruct=GetChannelWindowInformation(app);
            SelectedWindow=find(strcmp(app.DefinedWindowsDropDown.Value,ChannelStruct.AllWindowNames))-1;

            if SelectedWindow>0 & SelectedWindow<length(ChannelStruct.Windows)
            app.AreaCalculationKnob.Value=ChannelStruct.Windows(SelectedWindow).AreaPolarity;
            app.PeakValueSwitch.Value=ChannelStruct.Windows(SelectedWindow).PeakPolarity;
            end
            UpdateWindowPlot(app);
        end

        % Button pushed function: DeleteSelectedWindowButton
        function DeleteSelectedWindowButtonPushed(app, event)
            Stream = app.DataStreamButtonGroup.SelectedObject.Text;
            Variable = app.VariableButtonGroup.SelectedObject.Text;

            AllChannels=app.ChannelDropDown.Items;
            Channel=app.ChannelDropDown.Value;
            CurrentChannel=find(strcmp(AllChannels,Channel));
            ChannelField=strcat(Variable(1:end-1),'_',num2str(CurrentChannel));

            VariableStruct=getfield(app.Variables,Variable);
            ChannelStruct=getfield(VariableStruct,ChannelField);

            SelectedWindow=find(strcmp(app.DefinedWindowsDropDown.Value,ChannelStruct.AllWindowNames));

            if SelectedWindow>1 & SelectedWindow<length(ChannelStruct.AllWindowNames)

                ChannelStruct.Windows(SelectedWindow-1)=[];
                ChannelStruct.AllWindowNames(SelectedWindow)=[];

                app.Variables=setfield(app.Variables,Variable,ChannelField,ChannelStruct);
                app.DefinedWindowsDropDown.Items=ChannelStruct.AllWindowNames;
                app.DefinedWindowsDropDown.Value='Select Window';

                UpdateWindowPlot(app)
            end
        end

        % Button pushed function: SaveWindowInformationButton
        function SaveWindowInformationButtonPushed(app, event)
            WindowInformation.LFPs=app.Variables.LFPs;
            WindowInformation.CSDs=app.Variables.CSDs;
            WindowInformation.LFPChannelNames=app.Variables.LFPChannelNames;
            WindowInformation.CSDChannelNames=app.Variables.CSDChannelNames;

            timevector=datestr(now, 'yymmdd-HHMMSS');
            f=dir(app.Variables.SaveFolder);
            for ii=3:length(f)
                if (~isempty(strfind(f(ii).name,'WindowInformation')) || ~isempty(strfind(f(ii).name,'ChannelInformation')) ) &&  ~isempty(strfind(f(ii).name,app.Variables.Flags.ImportVersion))
                    answer=questdlg(strcat('WindowInformation file already exists in ',app.Variables.SaveFolder,'. Do you want to overwrite?'),'Overwrite Data?','Overwrite','Save with Date-Time Suffix','Save with Date-Time Suffix');
                    break
                end
            end

            if exist('answer')
                switch answer
                    case 'Save with Date-Time Suffix'
                        SaveName=strcat(app.Variables.SaveFolder,filesep,'WindowInformation',timevector,'_',app.Variables.Flags.ImportVersion,'.mat');
                    case 'Overwrite'
                        SaveName=strcat(app.Variables.SaveFolder,filesep,'WindowInformation','_',app.Variables.Flags.ImportVersion,'.mat');
                end
            else
                SaveName=strcat(app.Variables.SaveFolder,filesep,'WindowInformation','_',app.Variables.Flags.ImportVersion,'.mat');
            end
            save(SaveName,'WindowInformation','-v7.3')

            app.Variables.Flags.AnalyzeVisibility=1;
        end

        % Button pushed function: LoadWindowInformationButton
        function LoadWindowInformationButtonPushed(app, event)
            if isfield(app.Variables,'OpenFrom')
                [LoadFile,LoadPath]=uigetfile(app.Variables.OpenFrom);
            else
            [LoadFile,LoadPath]=uigetfile;
            end

            if LoadFile
                load(strcat(LoadPath,filesep,LoadFile))

                app.Variables.LFPs=WindowInformation.LFPs;
%                 app.Variables.CSDs=WindowInformation.CSDs;
                app.Variables.CSDs=WindowInformation.CSDs;
                app.Variables.LFPChannelNames=WindowInformation.LFPChannelNames;
                app.Variables.CSDChannelNames=WindowInformation.CSDChannelNames;

                app.Variables.Flags.AnalyzeVisibility=1;
            else
                warning('File Not Selected')
            end

        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)

            %Get if filtered data is analyzed
            app.Variables=setfield(app.Variables,'Analyze','AnalyzeFiltered',app.AnalyzeFilteredDataCheckBox.Value);

            %Get Streams To Analyze
            if length(app.StreamstoAnalyzePanel.Children)==1
                if app.StreamstoAnalyzePanel.Children.Value
                    StreamName=app.StreamstoAnalyzePanel.Children.Text;
                    StreamsAnalyzed{1}=StreamName;
                    app.Variables=setfield(app.Variables,'Analyze',StreamName,struct());
                end
            else
                for iStreams=1:length(app.StreamstoAnalyzePanel.Children)
                    if app.StreamstoAnalyzePanel.Children(iStreams).Value
                        StreamName=app.StreamstoAnalyzePanel.Children(iStreams).Text;
                        if exist('StreamsAnalyzed')
                            StreamsAnalyzed{length(StreamsAnalyzed)+1}=StreamName;
                        else
                            StreamsAnalyzed{1}=StreamName;
                        end
                        app.Variables=setfield(app.Variables,'Analyze',StreamName,struct());
                    end
                end
            end
            if ~exist('StreamName')
                warning('No data stream selected to analyze');
                return
            end
            app.Variables=setfield(app.Variables,'Analyze','StreamsAnalyzed',StreamsAnalyzed);

            %Get Variables To Analyze
            if app.LFPsCheckBox.Value
                VariablesAnalyzed{1}='LFPs';
                for iStream=1:length(StreamsAnalyzed)
                    app.Variables.Analyze=setfield(app.Variables.Analyze,StreamsAnalyzed{iStream},'LFPs',struct());
                end
            end
            if app.CSDsCheckBox.Value
                if exist('VariablesAnalyzed')
                    VariablesAnalyzed{length(VariablesAnalyzed)+1}='CSDs';
                else
                    VariablesAnalyzed{1}='CSDs';
                end
                for iStream=1:length(StreamsAnalyzed)
                    app.Variables.Analyze=setfield(app.Variables.Analyze,StreamsAnalyzed{iStream},'CSDs',struct());
                end
            end
            app.Variables.Analyze.VariablesAnalyzed=VariablesAnalyzed;

            %Get Types of Analyses
            NumPossibleQuantifications=length(app.AnalysesPanel.Children);
            for iQuant=1:NumPossibleQuantifications

                if NumPossibleQuantifications==1
                    QuantName=app.AnalysesPanel.Children.Text;
                    QuantValue=app.AnalysesPanel.Children.Value;
                else
                    QuantName=app.AnalysesPanel.Children(iQuant).Text;
                    QuantValue=app.AnalysesPanel.Children(iQuant).Value;
                end

                SpaceLocations=strfind(QuantName,' ');
                if SpaceLocations
                    QuantName(SpaceLocations)=[];
                end

                if QuantValue
                    if exist('Quantifications')
                        Quantifications{length(Quantifications)+1}=QuantName;
                    else
                        Quantifications{1}=QuantName;
                    end
                    for iStream=1:length(StreamsAnalyzed)
                        for iVariable=1:length(VariablesAnalyzed)
                            app.Variables.Analyze=setfield(app.Variables.Analyze,StreamsAnalyzed{iStream},VariablesAnalyzed{iVariable},QuantName,struct());
                        end
                    end
                end

            end
            app.Variables.Analyze.QuantificationsAnalyzed=Quantifications;
            app.Variables.Results=struct();

            app=CalculateResults_v2(app);


            %Save Results
            AnalysisResults=app.Variables.Results;

            timevector=datestr(now, 'yymmdd-HHMMSS');
            f=dir(app.Variables.SaveFolder);
            for ii=3:length(f)
                if ~isempty(strfind(f(ii).name,'AnalysisResults')) &&  ~isempty(strfind(f(ii).name,app.Variables.Flags.ImportVersion))
                    answer=questdlg(strcat('AnalysisResults file already exists in ',app.Variables.SaveFolder,'. Do you want to overwrite?'),'Overwrite Data?','Overwrite','Save with Date-Time Suffix','Save with Date-Time Suffix');
                    break
                end
            end
            if exist('answer')
                switch answer
                    case 'Save with Date-Time Suffix'
                        SaveName=strcat(app.Variables.SaveFolder,filesep,'AnalysisResults',timevector,'_',app.Variables.Flags.ImportVersion,'.mat');
                    case 'Overwrite'
                        SaveName=strcat(app.Variables.SaveFolder,filesep,'AnalysisResults','_',app.Variables.Flags.ImportVersion,'.mat');
                end
            else
                SaveName=strcat(app.Variables.SaveFolder,filesep,'AnalysisResults','_',app.Variables.Flags.ImportVersion,'.mat');
            end
            save(SaveName,'AnalysisResults','-v7.3')
ExportAllResults_v3(app)

        end

        % Button pushed function: SetPathWhereFoldersOpenButton
        function SetPathWhereFoldersOpenButtonPushed(app, event)
            app.Variables.OpenFrom = uigetdir;
        end

        % Callback function
        function LaunchPlotExportAppButtonPushed(app, event)
            PlottingPreviewApp(app.Variables)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.RecordingParametersPanel = uipanel(app.LoadDataTab);
            app.RecordingParametersPanel.Title = 'Recording Parameters';
            app.NumberofChannelsEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.NumberofChannelsEditFieldLabel.Text = 'Number of Channels';
            app.NumberofChannelsEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.NumberofChannelsEditField.Value = 16;
            app.NumberofRecordingsEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.NumberofRecordingsEditFieldLabel.Enable = 'off';
            app.NumberofRecordingsEditFieldLabel.Visible = 'off';
            app.NumberofRecordingsEditFieldLabel.Text = 'Number of Recordings';
            app.NumberofRecordingsEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.NumberofRecordingsEditField.Enable = 'off';
            app.NumberofRecordingsEditField.Visible = 'off';
            app.NumberofRecordingsEditField.Value = 10;
            app.NumberofSweepsEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.NumberofSweepsEditFieldLabel.Text = 'Number of Sweeps';
            app.NumberofSweepsEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.NumberofSweepsEditField.Value = 9;
            app.ChoppedWindowDurationsEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.ChoppedWindowDurationsEditFieldLabel.Text = 'Chopped Window Duration [s]';
            app.SweepChopDuration = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.SweepChopDuration.Value = 1;
            app.StimulationIntensitiesCommaSeparatedEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.StimulationIntensitiesCommaSeparatedEditFieldLabel.Text = 'Stimulation Intensities (Comma Separated)';
            app.StimulationIntensitiesCommaSeparatedEditField = uieditfield(app.RecordingParametersPanel, 'text');
            app.StimulationIntensitiesCommaSeparatedEditField.Value = '10, 100, 200, 400, 600';
            app.ElectrodeSpacingmmEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.ElectrodeSpacingmmEditFieldLabel.Text = 'Electrode Spacing [mm]';
            app.ElectrodeSpacingEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.ElectrodeSpacingEditField.Value = 0.1;
            app.OutlierDetectBox = uicheckbox(app.RecordingParametersPanel);
            app.OutlierDetectBox.Text = 'Detect Outliers';
            app.FilterCheckBox = uicheckbox(app.RecordingParametersPanel);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterCheckBoxValueChanged, true);
            app.FilterCheckBox.Text = 'Filter';
            app.HighPassCutoffHzEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.HighPassCutoffHzEditFieldLabel.Visible = 'off';
            app.HighPassCutoffHzEditFieldLabel.Text = 'High Pass Cutoff [Hz]';
            app.HPText = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.HPText.Visible = 'off';
            app.LowPassCutoffHzEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.LowPassCutoffHzEditFieldLabel.Visible = 'off';
            app.LowPassCutoffHzEditFieldLabel.Text = 'Low Pass Cutoff [Hz]';
            app.LPText = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.LPText.Visible = 'off';
            app.ChooseImportableDataStreamsLabel = uilabel(app.RecordingParametersPanel);
            app.ChooseImportableDataStreamsLabel.Text = 'Choose Importable Data Streams';
            app.SelectExperimentToImportButton = uibutton(app.RecordingParametersPanel, 'push');
            app.SelectExperimentToImportButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentToImportButtonPushed, true);
            app.SelectExperimentToImportButton.Text = 'Select Experiment To Import';
            app.KnownBadChannelsCheckBox = uicheckbox(app.RecordingParametersPanel);
            app.KnownBadChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @KnownBadChannelsCheckBoxValueChanged, true);
            app.KnownBadChannelsCheckBox.Text = 'Known "Bad" Channels?';
            app.BadChannelsCommaSeparatedEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.BadChannelsCommaSeparatedEditFieldLabel.Text = 'Bad Channels (Comma Separated)';
            app.BadChannelsCommaSeparatedEditField = uieditfield(app.RecordingParametersPanel, 'text');
            app.Tree = uitree(app.RecordingParametersPanel, 'checkbox');
            app.FilenamingSchemeLabel = uilabel(app.RecordingParametersPanel);
            app.FilenamingSchemeLabel.Text = 'File naming Scheme:';
            app.BaseNamingSchemeLabel = uilabel(app.RecordingParametersPanel);
            app.BaseNamingSchemeLabel.Text = 'Base Naming Scheme:';
            app.BaseNamingSchemeEditField = uieditfield(app.RecordingParametersPanel, 'text');
            app.BaseNamingSchemeEditField.Value = 'Block-';
            app.OptionalExactRecordingIdentifiersLabel = uilabel(app.RecordingParametersPanel);
            app.OptionalExactRecordingIdentifiersLabel.Enable = 'off';
            app.OptionalExactRecordingIdentifiersLabel.Visible = 'off';
            app.OptionalExactRecordingIdentifiersLabel.Text = '(Optional) Exact Recording Identifiers (Comma Separated List):';
            app.OptionalExactRecordingIdentifiersCommaSeparatedListEditField = uieditfield(app.RecordingParametersPanel, 'text');
            app.OptionalExactRecordingIdentifiersCommaSeparatedListEditField.Enable = 'off';
            app.OptionalExactRecordingIdentifiersCommaSeparatedListEditField.Visible = 'off';
            app.ImportParametersLabel = uilabel(app.RecordingParametersPanel);
            app.ImportParametersLabel.Text = 'Import Parameters';
            app.SpecifySaveLocationButton = uibutton(app.LoadDataTab, 'push');
            app.SpecifySaveLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifySaveLocationButtonPushed, true);
            app.SpecifySaveLocationButton.Text = 'Specify Save Location';
            app.LoadDataButton = uibutton(app.LoadDataTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ImportDataButton = uibutton(app.LoadDataTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.ImportNewDataLabel = uilabel(app.LoadDataTab);
            app.ImportNewDataLabel.Text = 'Import New Data';
            app.WorkonPreviouslyImportedDataLabel = uilabel(app.LoadDataTab);
            app.WorkonPreviouslyImportedDataLabel.Text = {'Work on Previously'; 'Imported Data'};
            app.SetPathWhereFoldersOpenButton = uibutton(app.LoadDataTab, 'push');
            app.SetPathWhereFoldersOpenButton.ButtonPushedFcn = createCallbackFcn(app, @SetPathWhereFoldersOpenButtonPushed, true);
            app.SetPathWhereFoldersOpenButton.Text = 'Set Path Where Folders Open';
            app.SelectWindowsTab = uitab(app.TabGroup);
            app.SelectWindowsTab.Title = 'Select Windows';
            app.UIAxes = uiaxes(app.SelectWindowsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VariableButtonGroup = uibuttongroup(app.SelectWindowsTab);
            app.VariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VariableButtonGroupSelectionChanged, true);
            app.VariableButtonGroup.Title = 'Variable';
            app.LFPWindowButton = uiradiobutton(app.VariableButtonGroup);
            app.LFPWindowButton.Text = 'LFPs';
            app.LFPWindowButton.Value = true;
            app.CSDWindowButton = uiradiobutton(app.VariableButtonGroup);
            app.CSDWindowButton.Text = 'CSDs';
            app.DataStreamButtonGroup = uibuttongroup(app.SelectWindowsTab);
            app.DataStreamButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataStreamButtonGroupSelectionChanged, true);
            app.DataStreamButtonGroup.Title = 'Data Stream';
            app.RawWindowButton = uiradiobutton(app.DataStreamButtonGroup);
            app.RawWindowButton.Text = 'First Stream';
            app.RawWindowButton.Value = true;
            app.DefineWindowPanel = uipanel(app.SelectWindowsTab);
            app.DefineWindowPanel.Title = 'Define Window';
            app.WindownameLabel = uilabel(app.DefineWindowPanel);
            app.WindownameLabel.Text = 'Window name:';
            app.WindownameEditField = uieditfield(app.DefineWindowPanel, 'text');
            app.PeakValueSwitchLabel = uilabel(app.DefineWindowPanel);
            app.PeakValueSwitchLabel.Text = 'Peak Value';
            app.PeakValueSwitch = uiswitch(app.DefineWindowPanel, 'toggle');
            app.PeakValueSwitch.Items = {'Negative', 'Positive'};
            app.PeakValueSwitch.Value = 'Positive';
            app.AreaCalculationKnobLabel = uilabel(app.DefineWindowPanel);
            app.AreaCalculationKnobLabel.Text = 'Area Calculation';
            app.AreaCalculationKnob = uiknob(app.DefineWindowPanel, 'discrete');
            app.AreaCalculationKnob.Items = {'Positive', 'Negative', 'Total', 'Rectified'};
            app.AreaCalculationKnob.Value = 'Total';
            app.WindowStartmsEditFieldLabel = uilabel(app.DefineWindowPanel);
            app.WindowStartmsEditFieldLabel.Text = 'Window Start [ms]';
            app.WindowStartmsEditField = uieditfield(app.DefineWindowPanel, 'numeric');
            app.WindowStartmsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowStartmsEditFieldValueChanged, true);
            app.WindowEndmsEditFieldLabel = uilabel(app.DefineWindowPanel);
            app.WindowEndmsEditFieldLabel.Text = 'Window End [ms]';
            app.WindowEndmsEditField = uieditfield(app.DefineWindowPanel, 'numeric');
            app.WindowEndmsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEndmsEditFieldValueChanged, true);
            app.AcceptWindowButton = uibutton(app.DefineWindowPanel, 'push');
            app.AcceptWindowButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptWindowButtonPushed, true);
            app.AcceptWindowButton.Text = 'Accept Window';
            app.DefinedWindowsDropDownLabel = uilabel(app.DefineWindowPanel);
            app.DefinedWindowsDropDownLabel.Text = 'Defined Windows:';
            app.DefinedWindowsDropDown = uidropdown(app.DefineWindowPanel);
            app.DefinedWindowsDropDown.Items = {'Select Window'};
            app.DefinedWindowsDropDown.ValueChangedFcn = createCallbackFcn(app, @DefinedWindowsDropDownValueChanged, true);
            app.DefinedWindowsDropDown.Value = 'Select Window';
            app.DeleteSelectedWindowButton = uibutton(app.DefineWindowPanel, 'push');
            app.DeleteSelectedWindowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedWindowButtonPushed, true);
            app.DeleteSelectedWindowButton.Text = {'Delete'; 'Selected'; 'Window'};
            app.ChannelDropDownLabel = uilabel(app.DefineWindowPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.DefineWindowPanel);
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.FilteredWindowsCheckBox = uicheckbox(app.SelectWindowsTab);
            app.FilteredWindowsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilteredWindowsCheckBoxValueChanged, true);
            app.FilteredWindowsCheckBox.Text = 'Filtered';
            app.OverrideYAxisCheckBox = uicheckbox(app.SelectWindowsTab);
            app.OverrideYAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverrideYAxisCheckBoxValueChanged, true);
            app.OverrideYAxisCheckBox.Text = 'Override Y Axis';
            app.MinEditFieldLabel = uilabel(app.SelectWindowsTab);
            app.MinEditFieldLabel.Visible = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.SelectWindowsTab, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MinEditField.Visible = 'off';
            app.MaxEditFieldLabel = uilabel(app.SelectWindowsTab);
            app.MaxEditFieldLabel.Visible = 'off';
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.SelectWindowsTab, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Visible = 'off';
            app.SliceInfoText = uilabel(app.SelectWindowsTab);
            app.SaveWindowInformationButton = uibutton(app.SelectWindowsTab, 'push');
            app.SaveWindowInformationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWindowInformationButtonPushed, true);
            app.SaveWindowInformationButton.Text = 'Save Window Information';
            app.LoadWindowInformationButton = uibutton(app.SelectWindowsTab, 'push');
            app.LoadWindowInformationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWindowInformationButtonPushed, true);
            app.LoadWindowInformationButton.Text = 'Load Window Information';
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.StreamstoAnalyzePanel = uipanel(app.AnalyzeTab);
            app.StreamstoAnalyzePanel.Title = 'Streams to Analyze';
            app.AnalyzeRawBox = uicheckbox(app.StreamstoAnalyzePanel);
            app.AnalyzeRawBox.Text = 'First Stream';
            app.VariablesPanel = uipanel(app.AnalyzeTab);
            app.VariablesPanel.Title = 'Variables';
            app.LFPsCheckBox = uicheckbox(app.VariablesPanel);
            app.LFPsCheckBox.Text = 'LFPs';
            app.CSDsCheckBox = uicheckbox(app.VariablesPanel);
            app.CSDsCheckBox.Text = 'CSDs';
            app.AnalysesPanel = uipanel(app.AnalyzeTab);
            app.AnalysesPanel.Title = 'Analyses';
            app.PeakCheckBox = uicheckbox(app.AnalysesPanel);
            app.PeakCheckBox.Text = 'Peak';
            app.AreaCheckBox = uicheckbox(app.AnalysesPanel);
            app.AreaCheckBox.Text = 'Area';
            app.MaximumFirstDerivativeCheckBox = uicheckbox(app.AnalysesPanel);
            app.MaximumFirstDerivativeCheckBox.Text = 'Maximum First Derivative';
            app.MaximumSecondDerivativeCheckBox = uicheckbox(app.AnalysesPanel);
            app.MaximumSecondDerivativeCheckBox.Text = 'Maximum Second Derivative';
            app.AnalyzeButton = uibutton(app.AnalyzeTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.AnalyzeFilteredDataCheckBox = uicheckbox(app.AnalyzeTab);
            app.AnalyzeFilteredDataCheckBox.Text = 'Analyze Filtered Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDTAnalysisGUIv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end